classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ChoosethelevelSwitchLabel     matlab.ui.control.Label
        ChoosethelevelSwitch          matlab.ui.control.Switch
        InsertstartingvaluesButton    matlab.ui.control.StateButton
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        PulsewidthEditFieldLabel      matlab.ui.control.Label
        PulsewidthEditField           matlab.ui.control.NumericEditField
        STARTButton                   matlab.ui.control.StateButton
        STOPButton                    matlab.ui.control.StateButton
        ChoosethegenderButtonGroup    matlab.ui.container.ButtonGroup
        MaleButton                    matlab.ui.control.RadioButton
        FemaleButton                  matlab.ui.control.RadioButton
        ChoosethelocationButtonGroup  matlab.ui.container.ButtonGroup
        DorsalButton_2                matlab.ui.control.RadioButton
        PlantarButton                 matlab.ui.control.RadioButton
        HeelButton                    matlab.ui.control.RadioButton
        RightPanel                    matlab.ui.container.Panel
        AmplitudeEditField_2          matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label     matlab.ui.control.Label
        PulsewidthEditField_2         matlab.ui.control.NumericEditField
        PulsewidthEditField_2Label    matlab.ui.control.Label
        SelectedparametersLabel       matlab.ui.control.Label
        INTENSITYLabel                matlab.ui.control.Label
        TOOLOWButton                  matlab.ui.control.StateButton
        LOWButton_2                   matlab.ui.control.StateButton
        HIGHButton                    matlab.ui.control.StateButton
        TOOHIGHButton                 matlab.ui.control.StateButton
        TYPELabel                     matlab.ui.control.Label
        PLEASANTButton                matlab.ui.control.StateButton
        UNPLEASANTButton              matlab.ui.control.StateButton
        SENSATIONUNDERTHEELECTRODESLabel  matlab.ui.control.Label
        PLEASANTButton_2              matlab.ui.control.StateButton
        UNPLEASANTButton_2            matlab.ui.control.StateButton
        nextButton                    matlab.ui.control.StateButton
        LOCATIONOFSENSATIONLabel      matlab.ui.control.Label
        DorsalButton                  matlab.ui.control.StateButton
        intensityEditFieldLabel       matlab.ui.control.Label
        intensityEditField            matlab.ui.control.NumericEditField
        typeEditFieldLabel            matlab.ui.control.Label
        typeEditField                 matlab.ui.control.NumericEditField
        sensationEditFieldLabel       matlab.ui.control.Label
        sensationEditField            matlab.ui.control.NumericEditField
        positionEditFieldLabel        matlab.ui.control.Label
        positionEditField             matlab.ui.control.NumericEditField
        CHECKLabel                    matlab.ui.control.Label
        rewardEditFieldLabel          matlab.ui.control.Label
        rewardEditField               matlab.ui.control.NumericEditField
        countEditField                matlab.ui.control.NumericEditField
        rewardEditFieldLabel_2        matlab.ui.control.Label
        PlantarButton_2               matlab.ui.control.StateButton
        HeelButton_2                  matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

       
    methods (Access = public)
        
        function InvisibleVariables(app)
%             
%             app.SelectedparametersLabel.Visible = 'off';
%             app.AmplitudeEditField_2.Visible = 'off';
%             app.PulsewidthEditField_2.Visible = 'off';
%             
%             app.CHECKLabel.Visible = 'off';
%             app.positionEditField.Visible = 'off';
%             app.sensationEditField.Visible = 'off';
%             app.typeEditField.Visible = 'off';
%             app.intensityEditField.Visible = 'off';


            app.PulsewidthEditField.Visible = 'off';
            app.AmplitudeEditField.Visible = 'off';
            
            app.INTENSITYLabel.Visible = 'off';
            app.TOOLOWButton.Visible = 'off';
            app.LOWButton_2.Visible = 'off';
            app.HIGHButton.Visible = 'off';
            app.TOOHIGHButton.Visible = 'off';
            
            app.TYPELabel.Visible = 'off';
            app.PLEASANTButton.Visible = 'off';
            app.UNPLEASANTButton.Visible = 'off';
            
            app.SENSATIONUNDERTHEELECTRODESLabel.Visible = 'off';
            app.PLEASANTButton_2.Visible = 'off';
            app.UNPLEASANTButton_2.Visible = 'off';
            
            app.LOCATIONOFSENSATIONLabel.Visible = 'off';
            app.DorsalButton.Visible = 'off';
            app.nextButton.Visible = 'off';
            
        end
    end
    
    
        methods (Access = public)
        
        function VisibleVariables(app)
            
            app.PulsewidthEditField.Visible = 'on';
            app.AmplitudeEditField.Visible = 'on';
            
            app.INTENSITYLabel.Visible = 'on';
            app.TOOLOWButton.Visible = 'on';
            app.LOWButton_2.Visible = 'on';
            app.HIGHButton.Visible = 'on';
            app.TOOHIGHButton.Visible = 'on';
            
            app.TYPELabel.Visible = 'on';
            app.PLEASANTButton.Visible = 'on';
            app.UNPLEASANTButton.Visible = 'on';
            
            app.SENSATIONUNDERTHEELECTRODESLabel.Visible = 'on';
            app.PLEASANTButton_2.Visible = 'on';
            app.UNPLEASANTButton_2.Visible = 'on';
            
            app.LOCATIONOFSENSATIONLabel.Visible = 'on';
            app.DorsalButton.Visible = 'on';
            app.nextButton.Visible = 'on';
        end
    end


    methods (Access = public)
        
        function ResetRightPanel(app)
            %reset the value
            app.nextButton.Value = 0;
            app.STOPButton.Value = 0;
            
            app.TOOLOWButton.Value = 0;
            app.LOWButton_2.Value = 0;
            app.HIGHButton.Value = 0;
            app.TOOHIGHButton.Value = 0;
            
            app.PLEASANTButton.Value = 0;
            app.UNPLEASANTButton.Value = 0;
            
            app.PLEASANTButton_2.Value = 0;
            app.UNPLEASANTButton_2.Value = 0;
            
            app.DorsalButton.Value = 0;
            app.PlantarButton_2.Value = 0;
            app.HeelButton_2.Value = 0;

        end
    end


        methods (Access = public)
        
        function ResetLeftPanel(app)
            
            %set the default switch value to Low
            app.ChoosethelevelSwitch.Value = 'Low'; 
            value = app.ChoosethelevelSwitch.Value;
            setappdata(app.UIFigure,'status',value);
            
            %set default value for gender to male
            app.ChoosethegenderButtonGroup.SelectedObject.Text = 'Male';
            setappdata(app.UIFigure,'gender',0);  %male
            
            %set default value for location of characterization
            app.ChoosethelocationButtonGroup.SelectedObject.Text = 'Dorsal';
            setappdata(app.UIFigure,'feet',0); %dorsal
            
            %set default manual starting value to zero
            app.InsertstartingvaluesButton.Value = 0;
            app.PulsewidthEditField.Visible = 'off' ;
            app.AmplitudeEditField.Visible = 'off';
            
            %clear stop and start button
            app.STARTButton.Value = 0;
            app.STOPButton.Value = 0;
            
        end
    end


    methods (Access = public)
        
        function [InitialObservation, LoggedSignals] = myResetFunction(app)
            
            % Reset function 

%% load models to simulate the patient's answers
amplitudeArray = ( 1 : 1 : 16);
pulsewidthArray = ( 70 : 10 : 600);

%% set initial state

% set Initial state %default value --> not perceived
intensity = -1;
type = -1;
location = -1;

%set initial index for amplitude and pulse width arrays --> default value = first index 
A_initialIndex = 1; %set the initial index for amplitude
PW_initialIndex = 1; %set the initial index for pulse width

%% update LoggedSignals to transfer the information to the Reset Function
% Return initial environment state variables as logged signals.
LoggedSignals.State = [ intensity , type , location];
InitialObservation = LoggedSignals.State;

%% Use Logged signal to transfer parameters information
LoggedSignals.Parameters = [A_initialIndex ; PW_initialIndex];
                                       
LoggedSignals.AmplitudeArray = amplitudeArray;
LoggedSignals.PulsewidthArray = pulsewidthArray;

count = 0; 
LoggedSignals.Count = count;

%save variables in case of plot 
values = []; 
LoggedSignals.Values = values;

            
        end
    end
    
    
    methods (Access = public)
        
        function [NextObs,Reward,IsDone,LoggedSignals] = myStepFunction(app,Action,LoggedSignals)
            
% Custom step function to construct 
%
% This function applies the given action to the environment and evaluates
% the system dynamics for one simulation step.

%% tranform action from cell to array
action_amplitude = Action(1);
action_pulsewidth = Action(2);

%% unpacking

%unpack variables
count = LoggedSignals.Count;
values = LoggedSignals.Values;

% Unpack the state vector from the logged signals.
%% Define the algorithm parameters
Parameters = LoggedSignals.Parameters;

A_currentIndex = Parameters(1);
PW_currentIndex = Parameters(2);

%% Define action_amplitude and pulse width array
amplitudeArray = LoggedSignals.AmplitudeArray;
pulsewidthArray = LoggedSignals.PulsewidthArray;

    
%% implement the action
 A_currentIndex =  A_currentIndex + action_amplitude;
 PW_currentIndex = PW_currentIndex + action_pulsewidth;
 
%check if it is the first iteration --> set the initial parameters 

if count == 0
    
    %set first amplitude
    firstAmplitude = getappdata(app.UIFigure,'firstAmplitude');
    A_currentIndex = find ( amplitudeArray == firstAmplitude);
    
    %set first pulsewidth
    firstPulsewidth = getappdata(app.UIFigure,'firstPulsewidth');
    PW_currentIndex = find ( pulsewidthArray == firstPulsewidth);
end

%action_amplitude currentIndex check
if A_currentIndex > length(amplitudeArray)
    A_currentIndex = length(amplitudeArray);
    
elseif A_currentIndex < 1 
    A_currentIndex = 1;
    
end
    
%action_pulsewidth currentIndex check
if PW_currentIndex > length(pulsewidthArray)
    PW_currentIndex = length(pulsewidthArray);
    
elseif PW_currentIndex < 1 
    PW_currentIndex = 1;
    
end


%% apply action
amplitude = amplitudeArray(A_currentIndex);
pulsewidth = pulsewidthArray(PW_currentIndex);


%update the value
app.AmplitudeEditField_2.Value = amplitude;
app.PulsewidthEditField_2.Value = pulsewidth;


%waiting for pressing the "next button"
next = app.nextButton.Value;
stop = app.STOPButton.Value;

while next == 0 && stop ==0
    drawnow;
    next = app.nextButton.Value;
    stop = app.STOPButton.Value;
end


%reset right panel
ResetRightPanel(app);


%define the state
intensity = getappdata(app.UIFigure,'intensity');
type = getappdata(app.UIFigure,'type');

sensation = getappdata(app.UIFigure,'sensation');
position = getappdata(app.UIFigure,'position');

if sensation == 0 && position == 0
    
    location = 0; %good
    
else
    
    location = 1; %bad
    
   
end


%% default values when intensity state is not perceived
if intensity == -1
    type = -1;
    location = -1;
end


%% update logged signals
% Transform state to observation.
LoggedSignals.State = [ intensity , type , location ];
NextObs = LoggedSignals.State;

%update the parameters
LoggedSignals.Parameters = [A_currentIndex ; PW_currentIndex];


%% Get a reward

if string(getappdata(app.UIFigure,'status'))=="Low"
  
   Reward = rewardLow(app,NextObs);
    
else 

   Reward = rewardHigh(app,NextObs);

end


%update check
app.rewardEditField.Value = Reward;

%% Terminal condition --> stop button pressed

if stop == 1
    
    IsDone = true;
        
else
    
    IsDone = false;
    
end


%% Update plot parameters if needed (plot not implemented yet)
count = count +1;
LoggedSignals.Count = count;
app.countEditField.Value = count;

values(count,1) = amplitude;
values(count,2) = pulsewidth;
values(count,3) = amplitude*pulsewidth;
LoggedSignals.Values = values;
setappdata(app.UIFigure,'values',values);
     
        end
    end


    methods (Access = public)
        
        function Reward = rewardLow(app,NextObs)
            %positive
            if NextObs(1) == 0 && NextObs(2) == 0 && NextObs(3) == 0
                Reward = 15;
                
            elseif NextObs(1) == 0 && NextObs(2) == 1 && NextObs(3) == 0
                Reward = 12;
            
            elseif NextObs(1) == 0 && NextObs(2) == 0 && NextObs(3) == 1
                Reward = 11;
                
            elseif NextObs(1) == 0 && NextObs(2) == 1 && NextObs(3) == 1
                Reward = 10;   
                
            %less positive
            
            elseif NextObs(1) == 1 && NextObs(2) == 0 && NextObs(3) == 0
                Reward = 4;
                
            elseif NextObs(1) == 1 && NextObs(2) == 1 && NextObs(3) == 0
                Reward = 3;
                
            elseif NextObs(1) == 1 && NextObs(2) == 0 && NextObs(3) == 1
                Reward = 2;
                
            elseif NextObs(1) == 1 && NextObs(2) == 1 && NextObs(3) == 1
                Reward = 1; 
                
            
            %not perceived --> less negative
            elseif NextObs(1) == -1 && NextObs(2) == -1 && NextObs(3) == -1
                Reward = 0;
                
            
            %negative    
            elseif NextObs(1) == 2 && NextObs(2) == 0 && NextObs(3) == 0
                Reward = -1;
                
            elseif NextObs(1) == 2 && NextObs(2) == 1 && NextObs(3) == 0
                Reward = -2;
                
            elseif NextObs(1) == 2 && NextObs(2) == 0 && NextObs(3) == 1
                Reward = -3;
                
            elseif NextObs(1) == 2 && NextObs(2) == 1 && NextObs(3) == 1
                Reward = -4; 
                
            end
        end
    end
    
    
    methods (Access = public)
        
        function Reward = rewardHigh(app,NextObs)
            %positive
            if NextObs(1) == 1 && NextObs(2) == 0 && NextObs(3) == 0
                Reward = 15;
                
            elseif NextObs(1) == 1 && NextObs(2) == 1 && NextObs(3) == 0
                Reward = 12;
            
            elseif NextObs(1) == 1 && NextObs(2) == 0 && NextObs(3) == 1
                Reward = 11;
                
            elseif NextObs(1) == 1 && NextObs(2) == 1 && NextObs(3) == 1
                Reward = 10;   
                
            %less positive
            
            elseif NextObs(1) == 0 && NextObs(2) == 0 && NextObs(3) == 0
                Reward = 4;
                
            elseif NextObs(1) == 0 && NextObs(2) == 1 && NextObs(3) == 0
                Reward = 3;
                
            elseif NextObs(1) == 0 && NextObs(2) == 0 && NextObs(3) == 1
                Reward = 2;
                
            elseif NextObs(1) == 0 && NextObs(2) == 1 && NextObs(3) == 1
                Reward = 1; 
                
            
            %not perceived --> less negative
            elseif NextObs(1) == -1 && NextObs(2) == -1 && NextObs(3) == -1
                Reward = 0;
                
            
            %negative    
            elseif NextObs(1) == 2 && NextObs(2) == 0 && NextObs(3) == 0
                Reward = -1;
                
            elseif NextObs(1) == 2 && NextObs(2) == 1 && NextObs(3) == 0
                Reward = -2;
                
            elseif NextObs(1) == 2 && NextObs(2) == 0 && NextObs(3) == 1
                Reward = -3;
                
            elseif NextObs(1) == 2 && NextObs(2) == 1 && NextObs(3) == 1
                Reward = -4; 
                
            end
        end
    end


    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Invisible buttons
            %InvisibleVariables(app);
            %set the default value for manual initialization to zero and
            %the variables are invisible
            
            app.InsertstartingvaluesButton.Value = 0;
            app.PulsewidthEditField.Visible='off';
            app.AmplitudeEditField.Visible='off';
            
            %set the default switch value to Low
            value = app.ChoosethelevelSwitch.Value; 
            setappdata(app.UIFigure,'status',value);
            
            %set default value for gender to male
            setappdata(app.UIFigure,'gender',0);  %male
            
            %set default value for location of characterization
            setappdata(app.UIFigure,'feet',0); %dorsal
            
            %clear stop and start button
            app.STARTButton.Value = 0;
            app.STOPButton.Value = 0;


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {581, 581};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {194, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ChoosethelevelSwitch
        function ChoosethelevelSwitchValueChanged(app, event)
            %let to choose between low threshold and high threshold
            %characterization
            value = app.ChoosethelevelSwitch.Value; 
            setappdata(app.UIFigure,'status',value);
            
        end

        % Value changed function: InsertstartingvaluesButton
        function InsertstartingvaluesButtonValueChanged(app, event)
            value = app.InsertstartingvaluesButton.Value;
            
            if value==1 
                app.PulsewidthEditField.Visible='on';
                app.AmplitudeEditField.Visible='on';
                
            else
                app.PulsewidthEditField.Visible='off';
                app.AmplitudeEditField.Visible='off';
                
            end
            
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
                
            %put the variables visible
            %VisibleVariables(app);
            
            % count
            app.countEditField.Value = 0;
            
           %clear the stop button
           app.STOPButton.Value = 0;
           
           % set the initial parameters based on selection
           
           %Unpack the values 
           manual = app.InsertstartingvaluesButton.Value;
           gender = getappdata(app.UIFigure,'gender'); 
           feet = getappdata(app.UIFigure,'feet');
           
           %% define initial condition for low level.            
           %male conditions
           if manual == 0 && gender == 0 && feet == 0
               
               firstAmplitude = 7;
               firstPulsewidth = 80;
               setappdata(app.UIFigure,'firstAmplitude',firstAmplitude);
               setappdata(app.UIFigure,'firstPulsewidth',firstPulsewidth);
               
           elseif manual == 0 && gender == 0 && feet == 1
               
               firstAmplitude = 11 ;
               firstPulsewidth = 90;
               setappdata(app.UIFigure,'firstAmplitude',firstAmplitude);
               setappdata(app.UIFigure,'firstPulsewidth',firstPulsewidth);
               
           elseif manual == 0 && gender == 0 && feet == 2
               
               firstAmplitude = 12;
               firstPulsewidth = 120;
               setappdata(app.UIFigure,'firstAmplitude',firstAmplitude);
               setappdata(app.UIFigure,'firstPulsewidth',firstPulsewidth);  
               
           %female conditions
               
           elseif manual == 0 && gender == 1 && feet == 0
               
               firstAmplitude = 9 ;
               firstPulsewidth = 130 ;
               setappdata(app.UIFigure,'firstAmplitude',firstAmplitude);
               setappdata(app.UIFigure,'firstPulsewidth',firstPulsewidth);   
               
           elseif manual == 0 && gender == 1 && feet == 1
               
               firstAmplitude = 9;
               firstPulsewidth = 90;
               setappdata(app.UIFigure,'firstAmplitude',firstAmplitude);
               setappdata(app.UIFigure,'firstPulsewidth',firstPulsewidth);  
               
           elseif manual == 0 && gender == 1 && feet == 2

               firstAmplitude = 10;
               firstPulsewidth = 90;
               setappdata(app.UIFigure,'firstAmplitude',firstAmplitude);
               setappdata(app.UIFigure,'firstPulsewidth',firstPulsewidth); 
               
           %manual setting condition
               
           else
               
               firstAmplitude = app.AmplitudeEditField.Value;    
               firstPulsewidth = app.PulsewidthEditField.Value; 
               setappdata(app.UIFigure,'firstAmplitude',firstAmplitude);
               setappdata(app.UIFigure,'firstPulsewidth',firstPulsewidth); 
               
           end


            %starting with the algorithm
            
            %discrete observations
            obsInfo = rlFiniteSetSpec({[0,0,0],[0,1,0],[0,0,1],[0,1,1],...
                                       [1,0,0],[1,1,0],[1,0,1],[1,1,1],...
                                       [-1,-1,-1],...
                                       [2,0,0],[2,1,0],[2,0,1],[2,1,1]});

            
            %% actions definition
            
            % 1 increase ; 0 equal ; -1 decrease
            
            %[ A , PW] --> all the possible combinations 
            
            
            actInfo = rlFiniteSetSpec({ [1,1] , [1,0] , [1,-1] , ...
                                        [-1,1] , [-1,0] , [-1,-1] , ...
                                        [0,1] , [0,0] , [0,-1]});
                        
            ResetHandle = @()myResetFunction(app);
            StepHandle = @(Action,LoggedSignals)myStepFunction(app,Action,LoggedSignals);
            
%             env = rlFunctionEnv(obsInfo,actInfo,'myStepFunction', ResetHandle );

            env = rlFunctionEnv(obsInfo,actInfo,StepHandle, ResetHandle);
            
            %Fix the random generator seed for reproducibility
            rng(0);
            
                  
            %define the threshold based on low or high threshold
            
             if string(getappdata(app.UIFigure,'status'))=="Low"
                  
                   load agent1.mat;
                    
             else 
                   load agent2.mat

             end

               
            %start the simulation
           
            
            simOptions = rlSimulationOptions('MaxSteps',50);
            simOptions.NumSimulations = 1;  
            experience = sim(env,agent,simOptions);
            
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
                ResetRightPanel(app)

if string(getappdata(app.UIFigure,'status'))=="Low"
                  
               %if there is a similar value into the dataset use it as
               %starting point 
               
               %load dataset
               load TotalValues.mat
               
               %load low threshold results
               values = getappdata(app.UIFigure , 'values');
               %find low threshold
               chargeLow = values(end,3);
               amplitudeLow = values(end,1);
               pulsewidthLow = values(end,2);
               
               %dataset check
               charge_position = find (matrice(1:2:(end-1),3) == chargeLow);
               amplitude_dataset = matrice(charge_position*2-1,1);
               pulsewidth_dataset = matrice(charge_position*2,2);
               
               possible_position = find(amplitude_dataset == amplitudeLow);
               

               if ~isempty(possible_position)
                   newPulsewidth = pulsewidth_dataset(possible_position);
                   pulsewidthHigh = min(newPulsewidth);
                   
                   if pulsewidthHigh > pulsewidthLow
                       firstPulsewidth = pulsewidthHigh;
                       firstAmplitude = amplitudeLow; 
        helpdlg({'The suggested high threshold parameters are:','Amplitude:' num2str(firstAmplitude) , 'Pulse Width:' num2str(firstPulsewidth)});

                   end
                   
               else %%use low level values
                    firstAmplitude = amplitudeLow;
                    firstPulsewidth = pulsewidthLow;
       helpdlg({'The suggested high threshold parameters are:','Amplitude:' num2str(firstAmplitude) , 'Pulse Width:' num2str(firstPulsewidth)});

                   
               end
else
           helpdlg({'Characterization completed'});
end
 
        ResetLeftPanel(app);
              

        end

        % Callback function
        function CHARACTERIZATIONButtonValueChanged(app, event)
          
            app.TOOLOWButton.Visible = 'on';
            app.LOWButton_2.Visible = 'on';
            app.HIGHButton.Visible = 'on';
            app.TOOHIGHButton.Visible = 'on';
            app.INTENSITYLabel.Visible = 'on';
            app.CHARACTERIZATIONButton.Value = 0;

        end

        % Value changed function: TOOLOWButton
        function TOOLOWButtonValueChanged(app, event)
          
            app.LOWButton_2.Value = 0;
            app.HIGHButton.Value = 0;
            app.TOOHIGHButton.Value = 0;
            
            intensity = -1;
            type = -1;
            location = -1;
            
            setappdata(app.UIFigure,'intensity',intensity);
            app.intensityEditField.Value = intensity;

            setappdata(app.UIFigure,'type',type);
            setappdata(app.UIFigure,'location',location);

            %type charact visible
            app.TYPELabel.Visible = 'on';
            app.PLEASANTButton.Visible = 'on';
            app.UNPLEASANTButton.Visible = 'on';


        end

        % Value changed function: LOWButton_2
        function LOWButton_2ValueChanged(app, event)

            app.TOOLOWButton.Value = 0;
            app.HIGHButton.Value = 0;
            app.TOOHIGHButton.Value = 0;
            
            intensity = 0;
            app.intensityEditField.Value = intensity;
            setappdata(app.UIFigure,'intensity',intensity);

             %type charact visible
            app.TYPELabel.Visible = 'on';
            app.PLEASANTButton.Visible = 'on';
            app.UNPLEASANTButton.Visible = 'on';

        end

        % Value changed function: HIGHButton
        function HIGHButtonValueChanged(app, event)

            app.TOOLOWButton.Value = 0;
            app.LOWButton_2.Value = 0;
            app.TOOHIGHButton.Value = 0;
            
            intensity = 1;
            app.intensityEditField.Value = intensity;
            setappdata(app.UIFigure,'intensity',intensity);


            %type charact visible
            app.TYPELabel.Visible = 'on';
            app.PLEASANTButton.Visible = 'on';
            app.UNPLEASANTButton.Visible = 'on';

        end

        % Value changed function: TOOHIGHButton
        function TOOHIGHButtonValueChanged(app, event)

            app.TOOLOWButton.Value = 0;
            app.LOWButton_2.Value = 0;
            app.HIGHButton.Value = 0;
            
            intensity = 2;
            app.intensityEditField.Value = intensity;
            setappdata(app.UIFigure,'intensity',intensity);

            
            %type charact visible
            app.TYPELabel.Visible = 'on';
            app.PLEASANTButton.Visible = 'on';
            app.UNPLEASANTButton.Visible = 'on';

        end

        % Value changed function: PLEASANTButton
        function PLEASANTButtonValueChanged(app, event)
                
            app.UNPLEASANTButton.Value = 0;
            type = 0;
            app.typeEditField.Value = type;

            setappdata(app.UIFigure,'type',type);

            %sensation ue visible
            app.SENSATIONUNDERTHEELECTRODESLabel.Visible = 'on';
            app.PLEASANTButton_2.Visible = 'on';
            app.UNPLEASANTButton_2.Visible = 'on';
        end

        % Value changed function: UNPLEASANTButton
        function UNPLEASANTButtonValueChanged(app, event)
            app.PLEASANTButton.Value = 0;
            type = 1;
            app.typeEditField.Value = type;

            setappdata(app.UIFigure,'type',type);
                
            %sensation ue visible
            app.SENSATIONUNDERTHEELECTRODESLabel.Visible = 'on';
            app.PLEASANTButton_2.Visible = 'on';
            app.UNPLEASANTButton_2.Visible = 'on';
        end

        % Value changed function: PLEASANTButton_2
        function PLEASANTButton_2ValueChanged(app, event)
       
            app.UNPLEASANTButton_2.Value = 0;
            sensation = 0;   
            app.sensationEditField.Value = sensation;
            setappdata(app.UIFigure,'sensation',sensation);
            %location visible
            app.LOCATIONOFSENSATIONLabel.Visible = 'on';
            app.DorsalButton.Visible = 'on';
        end

        % Value changed function: UNPLEASANTButton_2
        function UNPLEASANTButton_2ValueChanged(app, event)

            app.PLEASANTButton_2.Value = 0;
            sensation = 1;
            app.sensationEditField.Value = sensation;
            setappdata(app.UIFigure,'sensation',sensation);

            %location visible
            app.LOCATIONOFSENSATIONLabel.Visible = 'on';
            app.DorsalButton.Visible = 'on';
        end

        % Value changed function: nextButton
        function nextButtonValueChanged(app, event)
          value = app.nextButton.Value;
          setappdata(app.UIFigure,'next',value);

        end

        % Value changed function: DorsalButton
        function DorsalButtonValueChanged(app, event)
%             value = app.DorsalButton.Value;
            I = imread('Dorsal.png');
            I = I(40:770,87:385,:);
            
            yg = size(I,1)/2;
            xg = size(I,2)/2;
            %define if the threshold to understand if the lcoation is good or not
            
            threshold = yg + 50;
            figure,imshow(I),hold on, plot(xg,yg,'b*'),hold on,plot(xg,threshold,'*g');
            
            [x,y]=ginput(1);
            plot(x,y,'*m'); 
            pause(1), close all
            
            if y > threshold
                
                position = 1; %bad position
                
            else
                
                position = 0; %good position
                
            end
            
           app.positionEditField.Value = position;
           setappdata(app.UIFigure,'position',position);


        end

        % Selection changed function: ChoosethegenderButtonGroup
        function ChoosethegenderButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosethegenderButtonGroup.SelectedObject;
            
            if string(selectedButton.Text) == "Female"
                
               setappdata(app.UIFigure,'gender',1); %female
               
            else
                
              setappdata(app.UIFigure,'gender',0);  %male

            end
        end

        % Selection changed function: ChoosethelocationButtonGroup
        function ChoosethelocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosethelocationButtonGroup.SelectedObject;
            
            if string(selectedButton.Text) == "Dorsal"
                
               setappdata(app.UIFigure,'feet',0); %dorsal
               
            elseif string(selectedButton.Text) == "Plantar"
                
                setappdata(app.UIFigure,'feet',1); %plantar
                
            else
                
                setappdata(app.UIFigure,'feet',2); %heel
                
            end
        end

        % Value changed function: PlantarButton_2
        function PlantarButton_2ValueChanged(app, event)
            %value = app.PlantarButton_2.Value;
            
            I = imread('Tibial.png');
            I = I(45:926,109:450,:);
            
            yg = size(I,1)/2;
            xg = size(I,2)/2;
            %define if the threshold to understand if the lcoation is good or not
            
            threshold = yg + 150;
            figure,imshow(I),hold on, plot(xg,yg,'b*'),hold on,plot(xg,threshold,'*g');
            
            [x,y]=ginput(1);
            plot(x,y,'*m'); 
            pause(1), close all
            
            if y > threshold
                
                position = 1; %bad position
                
            else
                
                position = 0; %good position
                
            end
            
           app.positionEditField.Value = position;
           setappdata(app.UIFigure,'position',position);
            
        end

        % Value changed function: HeelButton_2
        function HeelButton_2ValueChanged(app, event)
            %value = app.HeelButton_2.Value;
            
            I = imread('Heel.png');
            I = I(34:1040,105:648,:);
            
            yg = size(I,1)/2;
            xg = size(I,2)/2;
            %define if the threshold to understand if the lcoation is good or not
            
            threshold = yg;
            figure,imshow(I),hold on, plot(xg,yg,'b*'); %hold on,plot(xg,threshold,'*g');
            
            [x,y]=ginput(1);
            plot(x,y,'*m'); 
            pause(1), close all
            
            if y < threshold
                
                position = 1; %bad position
                
            else
                
                position = 0; %good position
                
            end
            
           app.positionEditField.Value = position;
           setappdata(app.UIFigure,'position',position);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChoosethelevelSwitchLabel = uilabel(app.LeftPanel);
            app.ChoosethelevelSwitchLabel.Text = 'Choose the level';
            app.ChoosethelevelSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ChoosethelevelSwitch.Items = {'Low', 'High'};
            app.ChoosethelevelSwitch.ValueChangedFcn = createCallbackFcn(app, @ChoosethelevelSwitchValueChanged, true);
            app.ChoosethelevelSwitch.Value = 'Low';
            app.InsertstartingvaluesButton = uibutton(app.LeftPanel, 'state');
            app.InsertstartingvaluesButton.ValueChangedFcn = createCallbackFcn(app, @InsertstartingvaluesButtonValueChanged, true);
            app.InsertstartingvaluesButton.Text = 'Insert starting values';
            app.AmplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PulsewidthEditFieldLabel = uilabel(app.LeftPanel);
            app.PulsewidthEditFieldLabel.Text = 'Pulse width';
            app.PulsewidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.STARTButton = uibutton(app.LeftPanel, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            app.ChoosethegenderButtonGroup = uibuttongroup(app.LeftPanel);
            app.ChoosethegenderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosethegenderButtonGroupSelectionChanged, true);
            app.ChoosethegenderButtonGroup.Title = 'Choose the gender';
            app.MaleButton = uiradiobutton(app.ChoosethegenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.ChoosethegenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.ChoosethelocationButtonGroup = uibuttongroup(app.LeftPanel);
            app.ChoosethelocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosethelocationButtonGroupSelectionChanged, true);
            app.ChoosethelocationButtonGroup.Title = 'Choose the location';
            app.DorsalButton_2 = uiradiobutton(app.ChoosethelocationButtonGroup);
            app.DorsalButton_2.Text = 'Dorsal';
            app.DorsalButton_2.Value = true;
            app.PlantarButton = uiradiobutton(app.ChoosethelocationButtonGroup);
            app.PlantarButton.Text = 'Plantar';
            app.HeelButton = uiradiobutton(app.ChoosethelocationButtonGroup);
            app.HeelButton.Text = 'Heel';
            app.RightPanel = uipanel(app.GridLayout);
            app.AmplitudeEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.AmplitudeEditField_2Label = uilabel(app.RightPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.PulsewidthEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.PulsewidthEditField_2Label = uilabel(app.RightPanel);
            app.PulsewidthEditField_2Label.Text = 'Pulse width';
            app.SelectedparametersLabel = uilabel(app.RightPanel);
            app.SelectedparametersLabel.Text = 'Selected parameters';
            app.INTENSITYLabel = uilabel(app.RightPanel);
            app.INTENSITYLabel.Text = 'INTENSITY';
            app.TOOLOWButton = uibutton(app.RightPanel, 'state');
            app.TOOLOWButton.ValueChangedFcn = createCallbackFcn(app, @TOOLOWButtonValueChanged, true);
            app.TOOLOWButton.Text = 'TOO LOW';
            app.LOWButton_2 = uibutton(app.RightPanel, 'state');
            app.LOWButton_2.ValueChangedFcn = createCallbackFcn(app, @LOWButton_2ValueChanged, true);
            app.LOWButton_2.Text = 'LOW';
            app.HIGHButton = uibutton(app.RightPanel, 'state');
            app.HIGHButton.ValueChangedFcn = createCallbackFcn(app, @HIGHButtonValueChanged, true);
            app.HIGHButton.Text = 'HIGH';
            app.TOOHIGHButton = uibutton(app.RightPanel, 'state');
            app.TOOHIGHButton.ValueChangedFcn = createCallbackFcn(app, @TOOHIGHButtonValueChanged, true);
            app.TOOHIGHButton.Text = 'TOO HIGH';
            app.TYPELabel = uilabel(app.RightPanel);
            app.TYPELabel.Text = 'TYPE';
            app.PLEASANTButton = uibutton(app.RightPanel, 'state');
            app.PLEASANTButton.ValueChangedFcn = createCallbackFcn(app, @PLEASANTButtonValueChanged, true);
            app.PLEASANTButton.Text = 'PLEASANT';
            app.UNPLEASANTButton = uibutton(app.RightPanel, 'state');
            app.UNPLEASANTButton.ValueChangedFcn = createCallbackFcn(app, @UNPLEASANTButtonValueChanged, true);
            app.UNPLEASANTButton.Text = 'UNPLEASANT';
            app.SENSATIONUNDERTHEELECTRODESLabel = uilabel(app.RightPanel);
            app.SENSATIONUNDERTHEELECTRODESLabel.Text = 'SENSATION UNDER THE ELECTRODES';
            app.PLEASANTButton_2 = uibutton(app.RightPanel, 'state');
            app.PLEASANTButton_2.ValueChangedFcn = createCallbackFcn(app, @PLEASANTButton_2ValueChanged, true);
            app.PLEASANTButton_2.Text = 'PLEASANT';
            app.UNPLEASANTButton_2 = uibutton(app.RightPanel, 'state');
            app.UNPLEASANTButton_2.ValueChangedFcn = createCallbackFcn(app, @UNPLEASANTButton_2ValueChanged, true);
            app.UNPLEASANTButton_2.Text = 'UNPLEASANT';
            app.nextButton = uibutton(app.RightPanel, 'state');
            app.nextButton.ValueChangedFcn = createCallbackFcn(app, @nextButtonValueChanged, true);
            app.nextButton.Text = 'next';
            app.LOCATIONOFSENSATIONLabel = uilabel(app.RightPanel);
            app.LOCATIONOFSENSATIONLabel.Text = 'LOCATION OF SENSATION';
            app.DorsalButton = uibutton(app.RightPanel, 'state');
            app.DorsalButton.ValueChangedFcn = createCallbackFcn(app, @DorsalButtonValueChanged, true);
            app.DorsalButton.Text = 'Dorsal';
            app.intensityEditFieldLabel = uilabel(app.RightPanel);
            app.intensityEditFieldLabel.Text = 'intensity';
            app.intensityEditField = uieditfield(app.RightPanel, 'numeric');
            app.typeEditFieldLabel = uilabel(app.RightPanel);
            app.typeEditFieldLabel.Text = 'type';
            app.typeEditField = uieditfield(app.RightPanel, 'numeric');
            app.sensationEditFieldLabel = uilabel(app.RightPanel);
            app.sensationEditFieldLabel.Text = 'sensation';
            app.sensationEditField = uieditfield(app.RightPanel, 'numeric');
            app.positionEditFieldLabel = uilabel(app.RightPanel);
            app.positionEditFieldLabel.Text = 'position';
            app.positionEditField = uieditfield(app.RightPanel, 'numeric');
            app.CHECKLabel = uilabel(app.RightPanel);
            app.CHECKLabel.Text = 'CHECK';
            app.rewardEditFieldLabel = uilabel(app.RightPanel);
            app.rewardEditFieldLabel.Text = 'reward';
            app.rewardEditField = uieditfield(app.RightPanel, 'numeric');
            app.countEditField = uieditfield(app.RightPanel, 'numeric');
            app.rewardEditFieldLabel_2 = uilabel(app.RightPanel);
            app.rewardEditFieldLabel_2.Text = 'count';
            app.PlantarButton_2 = uibutton(app.RightPanel, 'state');
            app.PlantarButton_2.ValueChangedFcn = createCallbackFcn(app, @PlantarButton_2ValueChanged, true);
            app.PlantarButton_2.Text = 'Plantar';
            app.HeelButton_2 = uibutton(app.RightPanel, 'state');
            app.HeelButton_2.ValueChangedFcn = createCallbackFcn(app, @HeelButton_2ValueChanged, true);
            app.HeelButton_2.Text = 'Heel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end